classdef grupo8_matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Velocidadarticular1Slider       matlab.ui.control.Slider
        Velocidadarticular1SliderLabel  matlab.ui.control.Label
        GoHomeButton                    matlab.ui.control.Button
        PosicionYField_2                matlab.ui.control.NumericEditField
        PosicinxLabel_4                 matlab.ui.control.Label
        PosicionXField_2                matlab.ui.control.NumericEditField
        PosicinxLabel_3                 matlab.ui.control.Label
        MoviendoZ                       matlab.ui.control.Lamp
        MoviendoLamp_3Label             matlab.ui.control.Label
        MoviendoI                       matlab.ui.control.Lamp
        MoviendoLamp_2Label             matlab.ui.control.Label
        MoviendoD                       matlab.ui.control.Lamp
        MoviendoLampLabel               matlab.ui.control.Label
        ConfirmarTrayectoriaButton      matlab.ui.control.Button
        PosicionYField                  matlab.ui.control.NumericEditField
        PosicinxLabel_2                 matlab.ui.control.Label
        PosicionXField                  matlab.ui.control.NumericEditField
        PosicinxLabel                   matlab.ui.control.Label
        ModoButtonGroup                 matlab.ui.container.ButtonGroup
        TrayectoriaButton               matlab.ui.control.RadioButton
        CinInversaButton                matlab.ui.control.RadioButton
        CinDirectaButton                matlab.ui.control.RadioButton
        LogTextArea                     matlab.ui.control.TextArea
        LogTextAreaLabel                matlab.ui.control.Label
        ConfirmarPinza                  matlab.ui.control.Button
        Giro                            matlab.ui.control.NumericEditField
        PinzaGauge                      matlab.ui.control.Gauge
        PinzagiroGaugeLabel             matlab.ui.control.Label
        PinzaSwitch                     matlab.ui.control.RockerSwitch
        PinzaSwitchLabel                matlab.ui.control.Label
        Comunicacion                    matlab.ui.control.Lamp
        PosicionenmmEditField           matlab.ui.control.NumericEditField
        PosicinenmmEditFieldLabel       matlab.ui.control.Label
        PosicionengradosEditField_2     matlab.ui.control.NumericEditField
        PosicinengradosEditField_2Label  matlab.ui.control.Label
        PosicionengradosEditField       matlab.ui.control.NumericEditField
        PosicinengradosEditFieldLabel   matlab.ui.control.Label
        ConfirmarButton_3               matlab.ui.control.Button
        ConfirmarButton_2               matlab.ui.control.Button
        ConfirmarButton                 matlab.ui.control.Button
        BrazointeriorMotorderechoGauge  matlab.ui.control.Gauge
        BrazointeriorMotorderechoGaugeLabel  matlab.ui.control.Label
        BrazoextremoMotorizquierdoGauge  matlab.ui.control.Gauge
        BrazoextremoMotorizquierdoGaugeLabel  matlab.ui.control.Label
        AltitudSlider                   matlab.ui.control.Slider
        AltitudSliderLabel              matlab.ui.control.Label
        DetenerComunicacion_Button      matlab.ui.control.Button
        IniciarComunicacionButton       matlab.ui.control.Button
        BaudRate                        matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel          matlab.ui.control.Label
        SerialPort                      matlab.ui.control.EditField
        SerialPortEditFieldLabel        matlab.ui.control.Label
        ArduinoControlconMATLABLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        TrayPuntosAxes                  matlab.ui.control.UIAxes
        AxesExtremo                     matlab.ui.control.UIAxes
        AxesStepper                     matlab.ui.control.UIAxes
        AxesInterior                    matlab.ui.control.UIAxes
    end


    % Matlab Interface for Arduino Robot Control
    %
    % Version 24.10.2022
    %
    % Grupo 8

    properties (Access = private)
        puertoSerie; % Declaración de propiedad para ser usada por toda la aplicación

        factor=1579;
        trayectoria_input;

        isMovingD=false;
        isMovingI=false;
        isMovingZ=false;
        diffLimit=2;
        mode = 0;
        x,y=0;
        p;
        tray_real;
        v_x;
        v_y;
        xinput;
        yinput;
        zinput;

        cntData1=0;
        nBuffer1=1001;
        buffer1_timeStamp=NaN*ones(1001,1);
        buffer1_pulsos=NaN*ones(1001,1);
        buffer1_posi=NaN*ones(1001,1);

        cntData2=0;
        nBuffer2=1001;
        buffer2_timeStamp=NaN*ones(1001,1);
        buffer2_pulsos=NaN*ones(1001,1);
        buffer2_posi=NaN*ones(1001,1);

        cntData3=0;
        nBuffer3=1001;
        buffer3_timeStamp=NaN*ones(1001,1);
        buffer3_pulsos=NaN*ones(1001,1);
        buffer3_posi=NaN*ones(1001,1);
    end

    methods (Access = private)

        function read_serialData(app, ~, ~)
            try
                msg=fscanf(app.puertoSerie, '%d, %d, %d, %d');

                % opcode format: motor, timestamp, pulsos, posi
                % opcode error: 4, motornumber,

                switch msg(1)
                    case 1
                        app.cntData1=app.cntData1+1; %Contador de datos recibidos
                        %Almacenamiento en buffer de los datos recibidos
                        if app.cntData1<app.nBuffer1
                            app.buffer1_timeStamp(app.cntData1)=msg(2)/1000; %Conversión a segundos
                            app.buffer1_pulsos(app.cntData1)=msg(3);
                            app.buffer1_posi(app.cntData1)=msg(4);
                        else
                            app.buffer1_timeStamp(end+1)=msg(2)/1000; %Conversión a segundos
                            app.buffer1_pulsos(end+1)=msg(3);
                            app.buffer1_posi(end+1)=msg(4);
                        end

                        if abs(msg(3)-msg(4))<app.diffLimit
                            app.isMovingD=false;
                            app.MoviendoD.Color='red';
                        else 
                            app.isMovingD=true;
                            app.MoviendoD.Color='green';
                        end

                        %Representacio del resultado de la señal
                        buffer1_plot=app.buffer1_pulsos(end-1000:end);
                        buffer1_plot_2=app.buffer1_posi(end-1000:end);
                        time1_array=0:0.01:10;

                        plot(app.AxesExtremo, time1_array, buffer1_plot, time1_array, buffer1_plot_2)
                    case 2
                        app.cntData2=app.cntData2+1; %Contador de datos recibidos
                        %Almacenamiento en buffer de los datos recibidos
                        if app.cntData2<app.nBuffer2
                            app.buffer2_timeStamp(app.cntData2)=msg(2)/1000; %Conversión a segundos
                            app.buffer2_pulsos(app.cntData2)=msg(3);
                            app.buffer2_posi(app.cntData2)=msg(4);
                        else
                            app.buffer2_timeStamp(end+1)=msg(2)/1000; %Conversión a segundos
                            app.buffer2_pulsos(end+1)=msg(3);
                            app.buffer2_posi(end+1)=msg(4);
                        end

                        if abs(msg(3)-msg(4))<app.diffLimit
                            app.isMovingI=false;
                            app.MoviendoI.Color='red';
                        else 
                            app.isMovingI=true;
                            app.MoviendoI.Color='green';
                        end

                        %Representacio del resultado de la señal
                        buffer2_plot=app.buffer2_pulsos(end-1000:end);
                        buffer2_plot_2=app.buffer2_posi(end-1000:end);
                        time2_array=0:0.01:10;

                        plot(app.AxesInterior, time2_array, buffer2_plot, time2_array, buffer2_plot_2)
                    case 3
                        app.cntData3=app.cntData3+1; %Contador de datos recibidos
                        %Almacenamiento en buffer de los datos recibidos
                        if app.cntData3<app.nBuffer3
                            app.buffer3_timeStamp(app.cntData3)=msg(2)/1000; %Conversión a segundos
                            app.buffer3_pulsos(app.cntData3)=msg(3);
                            app.buffer3_posi(app.cntData3)=msg(4);
                        else
                            app.buffer3_timeStamp(end+1)=msg(2)/1000; %Conversión a segundos
                            app.buffer3_pulsos(end+1)=msg(3);
                            app.buffer3_posi(end+1)=msg(4);
                        end

                        if abs(msg(3)-msg(4))<app.diffLimit
                            app.isMovingZ=false;
                            app.MoviendoZ.Color='red';
                        else 
                            app.isMovingZ=true;
                            app.MoviendoZ.Color='green';
                        end

                        %Representacio del resultado de la señal
                        buffer3_plot=app.buffer3_pulsos(end-1000:end);
                        buffer3_plot_2=app.buffer3_posi(end-1000:end);
                        time3_array=0:0.01:10;

                        plot(app.AxesStepper, time3_array, buffer3_plot, time3_array, buffer3_plot_2)
                    case 4
                        app.LogTextArea.Value = ["Error: pulsos fuera de campo de trabajo de motor ", msg(2), "pulsos: ", msg(3), "posi: ", msg(4)];
                    case 5
                        app.LogTextArea.Value = ["Error: Punto de Singularidad", msg(2), "x: ", msg(3), "y: ", msg(4)];
                    case 6
                        app.xinput = msg(2);
                        app.yinput = msg(3);
                        app.zinput = msg(4);
                        addpoints(app.tray_real, app.x, app.y);
                        app.mainplot = plot3(app.UIAxes, app.xinput, app.yinput, app.zinput, '-o');
                        addpoints 
                        drawnow
                end
            catch
                return;

            end

        end

        function [result] = sentido2sign(~, sentido)
            if sentido == 0
                result = -1;
            else
                result = 1;
            end
        end

        function sendCommands(app)
            %app.mode;
            opcode = [num2str(app.mode), ',',num2str(app.PosicionengradosEditField.Value), ',', num2str(app.PosicionengradosEditField_2.Value), ',', num2str(app.PosicionenmmEditField.Value), ',', num2str(app.Giro.Value), ',', num2str(app.PinzaSwitch.Value), ',', num2str(app.PosicionXField.Value), ',', num2str(app.PosicionYField.Value), ',', num2str(app.PosicionXField_2.Value), ',', num2str(app.PosicionYField_2.Value)];
            fprintf(app.puertoSerie, opcode);
            disp(opcode);
        end
        % brazo derecho - brazo interior
        function [result] = brazosDgrados(~, grados)
            result = grados*(app.factor/360)*4.5;
        end

        % brazo iqzierdo - brazo exterior
        function [result] = brazoIgrados(~, grados)
            result = grados*(app.factor/360)*7.281125;
        end

        function [result] = mmTopulsos(~, mm)
            result = mm*100;
        end

        function sendTray(app,x,y)
            for c = 1:(length(app.x)-1)
                app.PosicionXField.Value=x(c);
                app.PosicionXField_2.Value=x(c+1);
                app.PosicionYField.Value=y(c);
                app.PosicionYField_2.Value=y(c+1);
                sendCommands(app);
                while app.isMovingD && app.isMovingI && app.isMovingZ
                    pause(0.1);
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarComunicacionButton
        function IniciarComunicacionButtonPushed(app, event)

            app.LogTextArea.Value = "Iniciando Comuniación...";

            serialPort=app.SerialPort.Value; %Nombre del puerto

            %Busco si hay comunicacion abierta previa
            prevDevice=strcat('Serial-', serialPort);
            comPrev=instrfind('Name', prevDevice, 'Status', 'open');
            for i=1:length(comPrev)
                fclose(comPrev(i));
            end

            app.puertoSerie=serial(serialPort); %Defino el puerto que abrir
            app.puertoSerie.BaudRate=app.BaudRate.Value; %Establezco velocidad de la comunicación

            % Configuración para la recepción de mensajes
            app.puertoSerie.BytesAvailableFcnMode= 'terminator'; %Ejecutar función al encontrar el fin de mensaje
            app.puertoSerie.terminator='LF'; %Fin de mensaje: salto de linea

            app.puertoSerie.BytesAvailableFcn = {@app.read_serialData}; %Handler a la función
            fopen(app.puertoSerie); %Abro comunicacion

            app.LogTextArea.Value = "Comuniación iniciada!";
            app.Comunicacion.Color = 'green';
        end

        % Button pushed function: DetenerComunicacion_Button
        function DetenerComunicacion_ButtonPushed(app, event)
            app.goHome();
            pause(5);
            fclose(app.puertoSerie);
            app.Comunicacion.Color = 'red';

            assignin('base', 'buffer1_posi', app.buffer1_posi);
            assignin('base', 'buffer2_posi', app.buffer2_posi);
            assignin('base', 'buffer3_posi', app.buffer3_posi);
            assignin('base', 'buffer1_pulsos', app.buffer1_pulsos);
            assignin('base', 'buffer2_pulsos', app.buffer2_pulsos);
            assignin('base', 'buffer3_pulsos', app.buffer3_pulsos);
            assignin('base', 'buffer1_timeStamp', app.buffer1_timeStamp);
            assignin('base', 'buffer2_timeStamp', app.buffer2_timeStamp);
            assignin('base', 'buffer3_timeStamp', app.buffer3_timeStamp);

        end

        % Button pushed function: ConfirmarButton
        function ConfirmarButtonPushed(app, event)
            sendCommands(app);
            app.BrazointeriorMotorderechoGauge.Value=app.PosicionengradosEditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                fclose(app.puertoSerie);
            end
            delete(app)
        end

        % Button pushed function: ConfirmarButton_2
        function ConfirmarButton_2Pushed(app, event)
            app.BrazoextremoMotorizquierdoGauge.Value=app.PosicionengradosEditField_2.Value;
            sendCommands(app);
        end

        % Button pushed function: ConfirmarButton_3
        function ConfirmarButton_3Pushed(app, event)
            app.AltitudSlider.Value=app.PosicionenmmEditField.Value;
            sendCommands(app);
        end

        % Button pushed function: ConfirmarPinza
        function ConfirmarPinzaButtonPushed(app, event)
            sendCommands(app);
            app.PinzaGauge.Value=app.Giro.Value;

        end

        % Selection changed function: ModoButtonGroup
        function ModoButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModoButtonGroup.SelectedObject;
            if app.CinInversaButton.Value
                app.mode=1;
                app.PosicionengradosEditField.Enable="off";
                app.PosicionengradosEditField_2.Enable="off";
                app.PosicionXField.Enable="on";
                app.PosicionYField.Enable="on";
                app.PosicionXField_2.Enable="on";
                app.PosicionYField_2.Enable="on";
                app.TrayPuntosAxes.Visible="off";
                app.ConfirmarTrayectoriaButton.Enable="off";
            end
           if app.CinDirectaButton.Value
                app.mode=0;
                app.PosicionengradosEditField.Enable="on";
                app.PosicionengradosEditField_2.Enable="on";
                app.PosicionXField.Enable="off";
                app.PosicionYField.Enable="off";
                app.PosicionXField_2.Enable="off";
                app.PosicionYField_2.Enable="off";
                app.TrayPuntosAxes.Visible="off";
                app.ConfirmarTrayectoriaButton.Enable="off";
           end
           if app.TrayectoriaButton.Value
                app.mode=2;
                app.PosicionengradosEditField.Enable="off";
                app.PosicionengradosEditField_2.Enable="off";
                app.PosicionXField.Enable="on";
                app.PosicionYField.Enable="on";  
                app.PosicionXField_2.Enable="on";
                app.PosicionYField_2.Enable="on";
                app.TrayPuntosAxes.Visible="on";
                app.ConfirmarTrayectoriaButton.Enable="on";
           end
        end

        % Button down function: TrayPuntosAxes
        function TrayPuntosAxesButtonDown(app, event)
            delete(app.p);
            app.PosicionXField.Editable="off";
            app.PosicionYField.Editable="off";
            app.PosicionXField_2.Editable="off";
            app.PosicionYField_2.Editable="off";
            img = imread('Robot.png');
            % set(axes, 'YDir','normal')
            imshow(flipud(img), 'XData',0:400, 'YData',0:339);
            axis on;
            gca().set("YDir", 'normal');
            %RI = imref2d(size(img));
            %RI.XWorldLimits = [0 480];
            %RI.YWorldLimits = [0 407];
            %imshow(flipud(img), RI);
            [app.x,app.y]=ginput;
            app.x = round(app.x - 200);
            app.y = round(app.y -141);
            app.p = plot(app.TrayPuntosAxes, app.x, app.y);
            app.tray_real = animatedline(app.TrayPuntosAxes, app.xinput, app.yinput, 'Marker','o');
            close
        end

        % Button pushed function: ConfirmarTrayectoriaButton
        function ConfirmarTrayectoriaButtonPushed(app, event)
            sendTray(app, app.x, app.y);
            %polybuffer(app.trayectoria_input);
            app.PosicionXField_2.Editable="on";
            app.PosicionYField_2.Editable="on";            
            app.PosicionXField.Editable="on";
            app.PosicionYField.Editable="on";
        end

        % Button pushed function: GoHomeButton
        function goHome(app, event)
            opcode = [num2str(0), ",",num2str(0), ",",num2str(0), ",",num2str(0), ",",num2str(0), ",",num2str(0), ",",num2str(0), ",",num2str(0), ",",num2str(0), ",",num2str(0)];
            fprintf(app.puertoSerie, opcode);
            disp(opcode);
        end

        % Value changed function: Velocidadarticular1Slider
        function Velocidadarticular1SliderValueChanged(app, event)
            value = app.Velocidadarticular1Slider.Value;
            disp(value);
            app.Velocidadarticular1Slider.Value=0;
        end

        % Value changing function: Velocidadarticular1Slider
        function Velocidadarticular1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.v_y = changingValue;
            disp(app.v_y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AxesInterior = uiaxes(app.UIFigure);
            title(app.AxesInterior, 'PID 2')
            xlabel(app.AxesInterior, 'X')
            ylabel(app.AxesInterior, 'Y')
            zlabel(app.AxesInterior, 'Z')
            app.AxesStepper = uiaxes(app.UIFigure);
            title(app.AxesStepper, 'Paso a Paso')
            xlabel(app.AxesStepper, 'X')
            ylabel(app.AxesStepper, 'Y')
            zlabel(app.AxesStepper, 'Z')
            app.AxesExtremo = uiaxes(app.UIFigure);
            title(app.AxesExtremo, 'PID 1')
            xlabel(app.AxesExtremo, 'X')
            ylabel(app.AxesExtremo, 'Y')
            zlabel(app.AxesExtremo, 'Z')
            app.TrayPuntosAxes = uiaxes(app.UIFigure);
            title(app.TrayPuntosAxes, 'Campo de Trabajo')
            xlabel(app.TrayPuntosAxes, 'X')
            ylabel(app.TrayPuntosAxes, 'Y')
            zlabel(app.TrayPuntosAxes, 'Z')
            app.TrayPuntosAxes.ButtonDownFcn = createCallbackFcn(app, @TrayPuntosAxesButtonDown, true);
            app.TrayPuntosAxes.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ArduinoControlconMATLABLabel = uilabel(app.UIFigure);
            app.ArduinoControlconMATLABLabel.Text = 'Arduino - Control con MATLAB';
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPort = uieditfield(app.UIFigure, 'text');
            app.SerialPort.Value = 'COM11';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRate = uieditfield(app.UIFigure, 'numeric');
            app.BaudRate.ValueDisplayFormat = '%.0f';
            app.BaudRate.Value = 9600;
            app.IniciarComunicacionButton = uibutton(app.UIFigure, 'push');
            app.IniciarComunicacionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarComunicacionButtonPushed, true);
            app.IniciarComunicacionButton.Text = 'Iniciar Comunicacion';
            app.DetenerComunicacion_Button = uibutton(app.UIFigure, 'push');
            app.DetenerComunicacion_Button.ButtonPushedFcn = createCallbackFcn(app, @DetenerComunicacion_ButtonPushed, true);
            app.DetenerComunicacion_Button.Text = 'Detener Comunicacion';
            app.AltitudSliderLabel = uilabel(app.UIFigure);
            app.AltitudSliderLabel.Text = 'Altitud';
            app.AltitudSlider = uislider(app.UIFigure);
            app.AltitudSlider.Limits = [0 150];
            app.BrazoextremoMotorizquierdoGaugeLabel = uilabel(app.UIFigure);
            app.BrazoextremoMotorizquierdoGaugeLabel.Text = {'Brazo extremo'; 'Motor izquierdo'};
            app.BrazoextremoMotorizquierdoGauge = uigauge(app.UIFigure, 'circular');
            app.BrazoextremoMotorizquierdoGauge.Limits = [-95 95];
            app.BrazointeriorMotorderechoGaugeLabel = uilabel(app.UIFigure);
            app.BrazointeriorMotorderechoGaugeLabel.Text = {'Brazo interior'; 'Motor derecho'};
            app.BrazointeriorMotorderechoGauge = uigauge(app.UIFigure, 'circular');
            app.BrazointeriorMotorderechoGauge.Limits = [-140 140];
            app.ConfirmarButton = uibutton(app.UIFigure, 'push');
            app.ConfirmarButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarButtonPushed, true);
            app.ConfirmarButton.Text = 'Confirmar';
            app.ConfirmarButton_2 = uibutton(app.UIFigure, 'push');
            app.ConfirmarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarButton_2Pushed, true);
            app.ConfirmarButton_2.Text = 'Confirmar';
            app.ConfirmarButton_3 = uibutton(app.UIFigure, 'push');
            app.ConfirmarButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarButton_3Pushed, true);
            app.ConfirmarButton_3.Text = 'Confirmar';
            app.PosicinengradosEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinengradosEditFieldLabel.Text = 'Posición (en grados)';
            app.PosicionengradosEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicinengradosEditField_2Label = uilabel(app.UIFigure);
            app.PosicinengradosEditField_2Label.Text = 'Posición (en grados)';
            app.PosicionengradosEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PosicinenmmEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinenmmEditFieldLabel.Text = 'Posición (en mm)';
            app.PosicionenmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Comunicacion = uilamp(app.UIFigure);
            app.PinzaSwitchLabel = uilabel(app.UIFigure);
            app.PinzaSwitchLabel.Text = 'Pinza';
            app.PinzaSwitch = uiswitch(app.UIFigure, 'rocker');
            app.PinzaSwitch.Items = {'Cerrada', 'Abierta'};
            app.PinzaSwitch.ItemsData = [1 0];
            app.PinzaSwitch.Value = 0;
            app.PinzagiroGaugeLabel = uilabel(app.UIFigure);
            app.PinzagiroGaugeLabel.Text = 'Pinza giro';
            app.PinzaGauge = uigauge(app.UIFigure, 'circular');
            app.PinzaGauge.Limits = [-175 175];
            app.Giro = uieditfield(app.UIFigure, 'numeric');
            app.ConfirmarPinza = uibutton(app.UIFigure, 'push');
            app.ConfirmarPinza.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarPinzaButtonPushed, true);
            app.ConfirmarPinza.Text = 'Confirmar';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.ModoButtonGroup = uibuttongroup(app.UIFigure);
            app.ModoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModoButtonGroupSelectionChanged, true);
            app.ModoButtonGroup.Title = 'Modo';
            app.CinDirectaButton = uiradiobutton(app.ModoButtonGroup);
            app.CinDirectaButton.Text = 'Cin. Directa';
            app.CinDirectaButton.Value = true;
            app.CinInversaButton = uiradiobutton(app.ModoButtonGroup);
            app.CinInversaButton.Text = 'Cin. Inversa';
            app.TrayectoriaButton = uiradiobutton(app.ModoButtonGroup);
            app.TrayectoriaButton.Text = 'Trayectoria ';
            app.PosicinxLabel = uilabel(app.UIFigure);
            app.PosicinxLabel.Enable = 'off';
            app.PosicinxLabel.Text = 'Posición (x_a)';
            app.PosicionXField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionXField.Enable = 'off';
            app.PosicinxLabel_2 = uilabel(app.UIFigure);
            app.PosicinxLabel_2.Enable = 'off';
            app.PosicinxLabel_2.Text = 'Posición (y_a)';
            app.PosicionYField = uieditfield(app.UIFigure, 'numeric');
            app.PosicionYField.Enable = 'off';
            app.ConfirmarTrayectoriaButton = uibutton(app.UIFigure, 'push');
            app.ConfirmarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmarTrayectoriaButtonPushed, true);
            app.ConfirmarTrayectoriaButton.Text = 'Confirmar Trayectoria';
            app.MoviendoLampLabel = uilabel(app.UIFigure);
            app.MoviendoLampLabel.Text = 'Moviendo';
            app.MoviendoD = uilamp(app.UIFigure);
            app.MoviendoLamp_2Label = uilabel(app.UIFigure);
            app.MoviendoLamp_2Label.Text = 'Moviendo';
            app.MoviendoI = uilamp(app.UIFigure);
            app.MoviendoLamp_3Label = uilabel(app.UIFigure);
            app.MoviendoLamp_3Label.Text = 'Moviendo';
            app.MoviendoZ = uilamp(app.UIFigure);
            app.PosicinxLabel_3 = uilabel(app.UIFigure);
            app.PosicinxLabel_3.Enable = 'off';
            app.PosicinxLabel_3.Text = 'Posición (x_b)';
            app.PosicionXField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PosicionXField_2.Enable = 'off';
            app.PosicinxLabel_4 = uilabel(app.UIFigure);
            app.PosicinxLabel_4.Enable = 'off';
            app.PosicinxLabel_4.Text = 'Posición (y_b)';
            app.PosicionYField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PosicionYField_2.Enable = 'off';
            app.GoHomeButton = uibutton(app.UIFigure, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @goHome, true);
            app.GoHomeButton.Text = 'Go Home';
            app.Velocidadarticular1SliderLabel = uilabel(app.UIFigure);
            app.Velocidadarticular1SliderLabel.Text = 'Velocidad articular 1';
            app.Velocidadarticular1Slider = uislider(app.UIFigure);
            app.Velocidadarticular1Slider.Limits = [-10 10];
            app.Velocidadarticular1Slider.ValueChangedFcn = createCallbackFcn(app, @Velocidadarticular1SliderValueChanged, true);
            app.Velocidadarticular1Slider.ValueChangingFcn = createCallbackFcn(app, @Velocidadarticular1SliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grupo8_matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end